classdef about < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure               matlab.ui.Figure
        ProgrammerAppDesignerLabel  matlab.ui.control.Label
        ProgrammerSupervisorLabel   matlab.ui.control.Label
        AlbertoCuadraLaraLabel      matlab.ui.control.Label
        MarcosVeraCoelloLabel       matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        CombustionToolbox10vLastupdate28012020Label  matlab.ui.control.Label
        Button_6                    matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        Button_9                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%% Resize app
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.3;
            bottom = screenHeight*0.25;
            width = 600;
            height = 434;
            app.AboutUIFigure.Position = [left bottom width height];
            %%%% Center app
            movegui(app.AboutUIFigure,'center');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            web('http://fluidosuc3m.es/','-browser');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            web('https://www.linkedin.com/in/albertocuadralara/','-browser');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            web('https://www.linkedin.com/in/marcos-vera-coello-05b3a643/?originalSubdomain=es','-browser');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            web('mailto:acuadra@ing.uc3m.es');
        end

        % Callback function
        function Button_9Pushed(app, event)
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             web('https://www.researchgate.net/profile/Marcos_Vera','-browser');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            web('https://www.researchgate.net/profile/Alberto_Cuadra_Lara','-browser');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('http://fluidosuc3m.es/people/acuadra/','-browser');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            web('http://fluidosuc3m.es/people/mvcoello/','-browser');
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About';
            app.ProgrammerAppDesignerLabel = uilabel(app.AboutUIFigure);
            app.ProgrammerAppDesignerLabel.Text = '- Programmer & App Designer.';
            app.ProgrammerSupervisorLabel = uilabel(app.AboutUIFigure);
            app.ProgrammerSupervisorLabel.Text = '- Programmer & Supervisor.';
            app.AlbertoCuadraLaraLabel = uilabel(app.AboutUIFigure);
            app.AlbertoCuadraLaraLabel.Text = 'Alberto Cuadra Lara';
            app.MarcosVeraCoelloLabel = uilabel(app.AboutUIFigure);
            app.MarcosVeraCoelloLabel.Text = 'Marcos Vera Coello';
            app.Button = uibutton(app.AboutUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'foto.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.AboutUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Logo-2C-128px-TM.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.AboutUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'Logo-2C-128px-TM.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.AboutUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'mvcoello240x360.jpg';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.AboutUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'logoFMRGv2.png';
            app.Button_5.Text = '';
            app.CombustionToolbox10vLastupdate28012020Label = uilabel(app.AboutUIFigure);
            app.CombustionToolbox10vLastupdate28012020Label.Text = 'Combustion Toolbox 1.0v. Last update 28/01/2020';
            app.Button_6 = uibutton(app.AboutUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'gmail.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.AboutUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'RG_square_green.jpg';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.AboutUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'RG_square_green.jpg';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.AboutUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Icon = 'GitHub-Mark-120px-plus.png';
            app.Button_9.Enable = 'off';
            app.Button_9.Text = '';
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end